classdef edititemapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        DeleteButton        matlab.ui.control.Button
        SaveButton          matlab.ui.control.Button
        AgeEditField        matlab.ui.control.NumericEditField
        AgeEditFieldLabel   matlab.ui.control.Label
        NameEditField       matlab.ui.control.EditField
        NameEditFieldLabel  matlab.ui.control.Label
        BackButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainapp % Description
        name
        age
        id

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, datafrommainapp)
            app.id = datafrommainapp;
            dbfile = fullfile("C:\Users\smutn\Desktop\matlab repo\MATLAB-and-SQLite","peopledb.db");
            conn = sqlite(dbfile);

            sqlquery = strcat('SELECT * FROM PEOPLE WHERE ID= ', num2str(app.id),';');
            results = fetch(conn,sqlquery);
            app.NameEditField.Value = table2array(results(1, 2));
            app.AgeEditField.Value = double(table2array(results(1, 3)));
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.mainapp=mainapp()
            app.delete
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.name = app.NameEditField.Value;
            
        end

        % Value changed function: AgeEditField
        function AgeEditFieldValueChanged(app, event)
            app.age = app.AgeEditField.Value;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            dbfile = fullfile("C:\Users\smutn\Desktop\matlab repo\MATLAB-and-SQLite","peopledb.db");
            conn = sqlite(dbfile);

            sqlquery = strcat('UPDATE PEOPLE SET NAME="', app.name, '",AGE=', num2str(app.age), ' WHERE ID=',num2str(app.id),';')
            execute(conn,sqlquery);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            dbfile = fullfile("C:\Users\smutn\Desktop\matlab repo\MATLAB-and-SQLite","peopledb.db");
            conn = sqlite(dbfile);

            sqlquery = strcat('DELETE FROM PEOPLE WHERE ID =', num2str(app.id),';')
            execute(conn,sqlquery);
            app.mainapp=mainapp()
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AgeEditField.Limits = [0 Inf];
            app.AgeEditField.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = edititemapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end