classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        AddDataButton  matlab.ui.control.Button
        UITable        matlab.ui.control.Table
    end

    
    properties (Access = private)
        edititemapp
        newitemapp
    end
    
    properties (Access = public)
        viewData % Description
        rawData % Description
    end
    
    methods (Access = public)
        
        function results = getData(app)
            dbfile = fullfile("C:\Users\smutn\Desktop\matlab repo\MATLAB-and-SQLite","peopledb.db");

            conn = sqlite(dbfile);
            tablename = "PEOPLE";
            results = sqlread(conn,tablename);
        end
    end
    
    methods (Access = private)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.rawData = getData(app);
            app.viewData = app.rawData(:,2:3);
            app.UITable.Data = app.viewData;
        end

        % Clicked callback: UITable
        function UITableClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;
            idOfClickedItem = table2array(app.rawData(displayRow, 1));
            app.edititemapp=edititemapp(app, idOfClickedItem)
            app.delete

        end

        % Button pushed function: AddDataButton
        function AddDataButtonPushed(app, event)
            app.newitemapp=newitemapp(app);
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Age'};
            app.UITable.RowName = {};
            app.UITable.ClickedFcn = createCallbackFcn(app, @UITableClicked, true);
            app.AddDataButton = uibutton(app.UIFigure, 'push');
            app.AddDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddDataButtonPushed, true);
            app.AddDataButton.Text = 'AddData';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end