classdef newitemapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AddUserButton       matlab.ui.control.Button
        AgeEditField        matlab.ui.control.NumericEditField
        AgeEditFieldLabel   matlab.ui.control.Label
        NameEditField       matlab.ui.control.EditField
        NameEditFieldLabel  matlab.ui.control.Label
        BackButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainapp % Description
        name
        age
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.mainapp=mainapp()
            app.delete
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.name = app.NameEditField.Value;
            
        end

        % Value changed function: AgeEditField
        function AgeEditFieldValueChanged(app, event)
            app.age = app.AgeEditField.Value;

        end

        % Button pushed function: AddUserButton
        function AddUserButtonPushed(app, event)
            dbfile = fullfile("C:\Users\smutn\Desktop\matlab repo\MATLAB-and-SQLite","peopledb.db");
            conn = sqlite(dbfile);
            sqlquery = strcat('INSERT INTO PEOPLE (NAME,AGE) VALUES ( "', app.name, '", ',num2str(app.age), ');');
            execute(conn,sqlquery)
            
            app.mainapp=mainapp()
            app.delete
        end

        % Callback function: not associated with a component
        function NameEditFieldValueChanging(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AgeEditField.Limits = [1 Inf];
            app.AgeEditField.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.AgeEditField.Value = 1;
            app.AddUserButton = uibutton(app.UIFigure, 'push');
            app.AddUserButton.ButtonPushedFcn = createCallbackFcn(app, @AddUserButtonPushed, true);
            app.AddUserButton.Text = 'Add User';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newitemapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end